classdef Alina_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button_7     matlab.ui.control.Button
        Button_6     matlab.ui.control.Button
        ButtonGroup  matlab.ui.container.ButtonGroup
        Button_5     matlab.ui.control.RadioButton
        Button_4     matlab.ui.control.RadioButton
        Button_3     matlab.ui.control.RadioButton
        Button_2     matlab.ui.control.RadioButton
        Button_1     matlab.ui.control.RadioButton
        EditField_7  matlab.ui.control.NumericEditField
        Label_7      matlab.ui.control.Label
        EditField_6  matlab.ui.control.NumericEditField
        Label_6      matlab.ui.control.Label
        EditField_5  matlab.ui.control.NumericEditField
        Label_5      matlab.ui.control.Label
        EditField_4  matlab.ui.control.NumericEditField
        Label_4      matlab.ui.control.Label
        EditField_3  matlab.ui.control.NumericEditField
        Label_3      matlab.ui.control.Label
        EditField_2  matlab.ui.control.NumericEditField
        Label_2      matlab.ui.control.Label
        EditField_1  matlab.ui.control.NumericEditField
        Label        matlab.ui.control.Label
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Magnitude % Амплитуда
        Phase % Начальная фаза
        Frequency % Несущая частота
        ModFrequency % Частота модуляции
        ModCoeff % Коэффициент модуляции
        AttCoeff % Коэффициент затухания
        TimeMax % Максимальное время
        Signal % Сигнал
        Time % Время
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            % Читаем значения параметров сигнала из полей ввода чисел
            app.Magnitude = app.EditField_1.Value;
            app.Phase = app.EditField_2.Value;
            app.Frequency = app.EditField_3.Value;
            app.ModFrequency = app.EditField_4.Value;
            app.ModCoeff = app.EditField_5.Value;
            app.AttCoeff = app.EditField_6.Value;
            app.TimeMax = app.EditField_7.Value;
            app.Time = (0:0.001:1.) * app.TimeMax; % Создаем массив отчетов времени
            switch app.ButtonGroup.SelectedObject.Text % Выбираем формулу расчета сигнала
                case app.Button_1.Text % Гармонический
                    app.Signal = app.Magnitude * sin(2 * pi * app.Frequency * app.Time + app.Phase);
                case app.Button_2.Text % Затухающий
                    app.Signal = app.Magnitude * exp(-app.AttCoeff .* app.Time) .* sin(2 * pi * app.Frequency * app.Time + app.Phase);
                case app.Button_3.Text % Амплитудная модуляция
                    app.Signal = app.Magnitude * (1. + app.ModCoeff * sin(2 * pi * app.ModFrequency * app.Time)) .* sin(2 * pi * app.Frequency * app.Time + app.Phase);
                case app.Button_4.Text % Фазовая модуляция
                    app.Signal = app.Magnitude * sin(2 * pi * app.Frequency * app.Time + app.Phase * (1 + app.ModCoeff * sin(2 * pi * app.ModFrequency * app.Time)));
                case app.Button_5.Text % Частотная модуляция
                    app.Signal = app.Magnitude * sin(2 * pi * app.Frequency * (1. + app.ModCoeff * app.Time) .* app.Time + app.Phase);
                otherwise
            end
            plot(app.UIAxes, app.Time, app.Signal);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            [fnm, pnm] = uiputfile({'*.csv'}, 'Сохранение сигнала в STL файл', 'modulated signal.csv'); % Получаем имя файла и папки от пользователя
            if fnm ~= 0 % Имя файла существует
                dlmwrite(fullfile([pnm, fnm]), app.Signal(:)); % Сохраняем массив сигналов в файл CSV
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Амплитуда сигнала от времени')
            xlabel(app.UIAxes, '\itt\rm, с')
            ylabel(app.UIAxes, '\itA\rm')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Амплитуда';
            app.EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_1.Value = 1;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Начальная фаза';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Value = 6.2832;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Несущая частота';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Value = 50;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Частота модуляции';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Value = 5;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Коэффициент модуляции';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.Value = 1;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Коэффициент затухания';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Value = 5;
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'Максимальное время';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.Value = 1;
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Выбор модуляции сигнала';
            app.Button_1 = uiradiobutton(app.ButtonGroup);
            app.Button_1.Text = 'Гармонический';
            app.Button_1.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = 'Затухающий';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = 'Амплитудная';
            app.Button_4 = uiradiobutton(app.ButtonGroup);
            app.Button_4.Text = 'Фазовая';
            app.Button_5 = uiradiobutton(app.ButtonGroup);
            app.Button_5.Text = 'Частотная';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = 'Построить';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = 'Сохранить в CSV';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Alina_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end